classdef DataExport < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure            % DataExport
        FilePathPanel        matlab.ui.container.Panel   % File Path
        DataPathButton       matlab.ui.control.Button   
        LabelEditField       matlab.ui.control.Label     % Data
        DataPath             matlab.ui.control.EditField
        ExportPathButton     matlab.ui.control.Button   
        Label                matlab.ui.control.Label     % Export
        ExportPath           matlab.ui.control.EditField
        FileNamePanel        matlab.ui.container.Panel   % File Name
        FileListBox          matlab.ui.control.ListBox  
        Label2               matlab.ui.control.Label     % Name
        FileNameTemplate     matlab.ui.control.EditField
        LabelDropDown        matlab.ui.control.Label     % Modify Date
        FileDateSortDropDown matlab.ui.control.DropDown  % Descend, Ascend
        FileExportPanel      matlab.ui.container.Panel   % File Export
        ExportButton         matlab.ui.control.Button    % Export
        LabelDropDown2       matlab.ui.control.Label     % Source
        FileSourceDropDown   matlab.ui.control.DropDown  % Ripple, Blackroc...
        LabelLamp            matlab.ui.control.Label     % Idle
        StatusLamp           matlab.ui.control.Lamp     
        Parallel             matlab.ui.control.CheckBox  % Parallel Export
        ErrorFile            matlab.ui.control.ListBox  
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            preference = NeuroAnalysis.Base.LoadVar('DataExport','DataExportPreference');
            if ~isempty(preference)
                app.DataPath.Value=preference.datapath;
                app.ExportPath.Value=preference.exportpath;
                app.FileNameTemplate.Value=preference.filenametemplate;
                app.FileDateSortDropDown.Value=preference.filedatesort;
                app.FileSourceDropDown.Value=preference.filesource;
                app.Parallel.Value = preference.parallel;
            end
            DataPathChanged(app);
        end

        % DataPathButton button pushed function
        function ChooseDataPath(app)
            hr = uigetdir('','Choose Data Path');
            if isa(hr,'char')
                app.DataPath.Value = hr;
                DataPathChanged(app);
            end
        end

        % DataPath value changed function
        function DataPathChanged(app)
            datapath = app.DataPath.Value;
            datanametemplate = app.FileNameTemplate.Value;
            fs = dir(fullfile(datapath,datanametemplate));
            fsname = arrayfun(@(i)i.name,fs,'UniformOutput',false);
            fsdatenum = arrayfun(@(i)i.datenum,fs);
            sortmethod = app.FileDateSortDropDown.Value;
            [~,sorti] = sort(fsdatenum,sortmethod);
            
            app.FileListBox.Items = fsname(sorti);
        end

        % FileNameTemplate value changed function
        function FileNameTemplateChanged(app)
            DataPathChanged(app);
        end

        % FileDateSortDropDown value changed function
        function FileDateSortChanged(app, event)
            DataPathChanged(app);
        end

        % ExportPathButton button pushed function
        function ChooseExportPath(app)
            hr = uigetdir('','Choose Data Export Path');
            if isa(hr,'char')
                app.ExportPath.Value = hr;
            end
        end

        % ExportButton button pushed function
        function Export(app)
            datapath = app.DataPath.Value;
            exportpath = app.ExportPath.Value;
            if(exist(exportpath,'dir')~=7)
                mkdir(exportpath);
            end
            datasource = app.FileSourceDropDown.Value;
            filename = app.FileListBox.Value;
            datapath = cellfun(@(x)fullfile(datapath,x),filename,'UniformOutput',false);
            isparallel = app.Parallel.Value;
            if ~isempty(datapath)
                app.ErrorFile.Items={};
                
                app.StatusLamp.Color = [0.12,0.85,0.12];
                app.LabelLamp.Text = 'Running';
                results = NeuroAnalysis.IO.Export(datapath,exportpath,datasource,isparallel);
                app.StatusLamp.Color = [0.12,0.15,0.12];
                app.LabelLamp.Text = 'Idle';
                
                status = cellfun(@(x)x.status,results);
                if any(~status)
                    source = cellfun(@(x)x.source,results);
                    app.ErrorFile.Items = source(~status);
                end
            end
        end

        % UIFigure close request function
        function closerequestFcn(app)
            preference.datapath = app.DataPath.Value;
            preference.exportpath = app.ExportPath.Value;
            preference.filenametemplate = app.FileNameTemplate.Value;
            preference.filedatesort = app.FileDateSortDropDown.Value;
            preference.filesource = app.FileSourceDropDown.Value;
            preference.parallel = app.Parallel.Value;
            
            NeuroAnalysis.Base.SaveVar('DataExport',preference,'DataExportPreference');
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'DataExport';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closerequestFcn);
            setAutoResize(app, app.UIFigure, true)
            app.FilePathPanel = uipanel(app.UIFigure);
            app.FilePathPanel.Title = 'File Path';
            app.FilePathPanel.Units = 'pixels';
            app.DataPathButton = uibutton(app.FilePathPanel, 'push');
            app.DataPathButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseDataPath);
            app.DataPathButton.Text = '...';
            app.LabelEditField = uilabel(app.FilePathPanel);
            app.LabelEditField.Text = 'Data';
            app.DataPath = uieditfield(app.FilePathPanel, 'text');
            app.DataPath.ValueChangedFcn = createCallbackFcn(app, @DataPathChanged);
            app.ExportPathButton = uibutton(app.FilePathPanel, 'push');
            app.ExportPathButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseExportPath);
            app.ExportPathButton.Text = '...';
            app.Label = uilabel(app.FilePathPanel);
            app.Label.Text = 'Export';
            app.ExportPath = uieditfield(app.FilePathPanel, 'text');
            app.FileNamePanel = uipanel(app.UIFigure);
            app.FileNamePanel.Title = 'File Name';
            app.FileNamePanel.Units = 'pixels';
            app.FileListBox = uilistbox(app.FileNamePanel);
            app.FileListBox.Items = {};
            app.FileListBox.Multiselect = 'on';
            app.FileListBox.Value = {};
            app.Label2 = uilabel(app.FileNamePanel);
            app.Label2.Text = 'Name';
            app.FileNameTemplate = uieditfield(app.FileNamePanel, 'text');
            app.FileNameTemplate.ValueChangedFcn = createCallbackFcn(app, @FileNameTemplateChanged);
            app.LabelDropDown = uilabel(app.FileNamePanel);
            app.LabelDropDown.Text = 'Modify Date';
            app.FileDateSortDropDown = uidropdown(app.FileNamePanel);
            app.FileDateSortDropDown.Items = {'Descend', 'Ascend'};
            app.FileDateSortDropDown.ValueChangedFcn = createCallbackFcn(app, @FileDateSortChanged, true);
            app.FileDateSortDropDown.Value = 'Descend';
            app.FileExportPanel = uipanel(app.UIFigure);
            app.FileExportPanel.Title = 'File Export';
            app.FileExportPanel.Units = 'pixels';
            app.ExportButton = uibutton(app.FileExportPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @Export);
            app.ExportButton.Text = 'Export';
            app.LabelDropDown2 = uilabel(app.FileExportPanel);
            app.LabelDropDown2.Text = 'Source';
            app.FileSourceDropDown = uidropdown(app.FileExportPanel);
            app.FileSourceDropDown.Items = {'Ripple', 'Blackrock', 'Plexon'};
            app.FileSourceDropDown.Value = 'Ripple';
            app.LabelLamp = uilabel(app.FileExportPanel);
            app.LabelLamp.Text = 'Idle';
            app.StatusLamp = uilamp(app.FileExportPanel);
            app.Parallel = uicheckbox(app.FileExportPanel);
            app.Parallel.Text = 'Parallel Export';
            app.Parallel.Value = true;
            app.ErrorFile = uilistbox(app.FileExportPanel);
            app.ErrorFile.Items = {};
            app.ErrorFile.Multiselect = 'on';
            app.ErrorFile.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = DataExport()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end